classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button_Up           matlab.ui.control.Button
        Button_Left         matlab.ui.control.Button
        Button_Down         matlab.ui.control.Button
        Button_Right        matlab.ui.control.Button
        ManualButton        matlab.ui.control.Button
        LineFollowerButton  matlab.ui.control.Button
        Button_Stop         matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
           set_param('Project_simulink/Constant1','Value','1');
           app.Button_Left.Visible = 'on';
           app.Button_Right.Visible  = 'on';
           app.Button_Up.Visible = 'on';
           app.Button_Down.Visible = 'on';
           app.Button_Stop.Visible = 'on';
           app.Button_Left.Enable = 'on';
           app.Button_Right.Enable = 'on';
           app.Button_Up.Enable = 'on';
           app.Button_Down.Enable = 'on';
           app.Button_Stop.Enable = 'on';
         
        end

        % Button pushed function: LineFollowerButton
        function LineFollowerButtonPushed(app, event)
           set_param('Project_simulink/Constant1','Value','2');
           app.Button_Left.Visible = 'off';
           app.Button_Right.Visible  = 'off';
           app.Button_Up.Visible = 'off';
           app.Button_Down.Visible = 'off';
           app.Button_Stop.Visible = 'off';
           app.Button_Left.Enable = 'off';
           app.Button_Right.Enable = 'off';
           app.Button_Up.Enable = 'off';
           app.Button_Down.Enable = 'off';
           app.Button_Stop.Enable = 'off';
        end

        % Button pushed function: Button_Up
        function Button_UpPushed(app, event)
            load_system('Project_simulink.slx')
            %open_system('project_simulink/Scope')
            set_param('Project_simulink','SimulationCommand','start')
            set_param('Project_simulink','SimulationCommand','update')
            %Value has one of 4 Values 
            %1 for forward, 2 for backward, 3 for turn right, 4 for turn left, 5 for stop
            %
            set_param('Project_simulink/Constant','Value','1');
        end

        % Button pushed function: Button_Down
        function Button_DownPushed(app, event)
                        load_system('Project_simulink.slx')
            %open_system('project_simulink/Scope')
            set_param('Project_simulink','SimulationCommand','start')
            set_param('Project_simulink','SimulationCommand','update')
            %Value has one of 4 Values 
            %1 for forward, 2 for backward, 3 for turn right, 4 for turn left, 5 for stop
            %
            set_param('Project_simulink/Constant','Value','2');
        end

        % Button pushed function: Button_Right
        function Button_RightPushed(app, event)
             load_system('Project_simulink.slx')
            %open_system('project_simulink/Scope')
            set_param('Project_simulink','SimulationCommand','start')
            set_param('Project_simulink','SimulationCommand','update')
            %Value has one of 4 Values 
            %1 for forward, 2 for backward, 3 for turn right, 4 for turn left, 5 for stop
            %
            set_param('Project_simulink/Constant','Value','3');
        end

        % Button pushed function: Button_Left
        function Button_LeftPushed(app, event)
            load_system('Project_simulink.slx')
            %open_system('project_simulink/Scope')
            set_param('Project_simulink','SimulationCommand','start')
            set_param('Project_simulink','SimulationCommand','update')
            %Value has one of 4 Values 
            %1 for forward, 2 for backward, 3 for turn right, 4 for turn left, 5 for stop
            %
            set_param('Project_simulink/Constant','Value','4');
        end

        % Button pushed function: Button_Stop
        function Button_StopPushed(app, event)
            load_system('Project_simulink.slx')
            %open_system('project_simulink/Scope')
            set_param('Project_simulink','SimulationCommand','start')
            set_param('Project_simulink','SimulationCommand','update')
            %Value has one of 4 Values 
            %1 for forward, 2 for backward, 3 for turn right, 4 for turn left, 5 for stop
            %
            set_param('Project_simulink/Constant','Value','5');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button_Up = uibutton(app.UIFigure, 'push');
            app.Button_Up.ButtonPushedFcn = createCallbackFcn(app, @Button_UpPushed, true);
            app.Button_Up.Icon = '1.jpg';
            app.Button_Up.Visible = 'off';
            app.Button_Up.Text = '';
            app.Button_Left = uibutton(app.UIFigure, 'push');
            app.Button_Left.ButtonPushedFcn = createCallbackFcn(app, @Button_LeftPushed, true);
            app.Button_Left.Icon = '2.jpg';
            app.Button_Left.Visible = 'off';
            app.Button_Left.Text = '';
            app.Button_Down = uibutton(app.UIFigure, 'push');
            app.Button_Down.ButtonPushedFcn = createCallbackFcn(app, @Button_DownPushed, true);
            app.Button_Down.Icon = '4.jpg';
            app.Button_Down.Visible = 'off';
            app.Button_Down.Text = '';
            app.Button_Right = uibutton(app.UIFigure, 'push');
            app.Button_Right.ButtonPushedFcn = createCallbackFcn(app, @Button_RightPushed, true);
            app.Button_Right.Icon = '3.jpg';
            app.Button_Right.Visible = 'off';
            app.Button_Right.Text = '';
            app.ManualButton = uibutton(app.UIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.LineFollowerButton = uibutton(app.UIFigure, 'push');
            app.LineFollowerButton.ButtonPushedFcn = createCallbackFcn(app, @LineFollowerButtonPushed, true);
            app.LineFollowerButton.Text = 'Line Follower';
            app.Button_Stop = uibutton(app.UIFigure, 'push');
            app.Button_Stop.ButtonPushedFcn = createCallbackFcn(app, @Button_StopPushed, true);
            app.Button_Stop.Icon = 'pngtree-vector-stop-icon-png-image_4255396.jpg';
            app.Button_Stop.Enable = 'off';
            app.Button_Stop.Visible = 'off';
            app.Button_Stop.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end